classdef optimal_thickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UnitsDropDownLabel              matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        ThicknessButton                 matlab.ui.control.Button
        ThicknessEditField              matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel        matlab.ui.control.Label
        PhaseShiftEditField             matlab.ui.control.NumericEditField
        OptimalThicknessLabel           matlab.ui.control.Label
        MaterialsDropDownLabel          matlab.ui.control.Label
        MaterialsDropDown               matlab.ui.control.DropDown
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        NumericalApertureEditFieldLabel  matlab.ui.control.Label
        NumericalApertureEditField      matlab.ui.control.NumericEditField
        PitchnmEditFieldLabel           matlab.ui.control.Label
        PitchnmEditField                matlab.ui.control.NumericEditField
        MaskAxis                        matlab.ui.control.UIAxes
        ImageButton                     matlab.ui.control.Button
        AerialImageAxes                 matlab.ui.control.UIAxes
        AerialImageLabel                matlab.ui.control.Label
        WaferLabel                      matlab.ui.control.Label
        WaferAxes                       matlab.ui.control.UIAxes
        DosemJcm2EditFieldLabel         matlab.ui.control.Label
        DosemJcm2EditField              matlab.ui.control.NumericEditField
        ThresholddecimalEditFieldLabel  matlab.ui.control.Label
        ThresholddecimalEditField       matlab.ui.control.NumericEditField
        NpixelsEditFieldLabel           matlab.ui.control.Label
        NpixelsEditField                matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel           matlab.ui.control.Label
        PeriodsEditField                matlab.ui.control.NumericEditField
        FWHMnmEditFieldLabel            matlab.ui.control.Label
        FWHMnmEditField                 matlab.ui.control.NumericEditField
        BlursLabel                      matlab.ui.control.Label
        AmplitudenmEditFieldLabel       matlab.ui.control.Label
        AmplitudenmEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        materials 
        deltas
        betas
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         [materials, deltas, betas] = parseIndexFromDataFile('data/euv-materials.xlsx');

            % Create MaterialsDropDown
            app.MaterialsDropDown.Items = materials;
            
            app.materials = materials;
            app.deltas = deltas;
            app.betas = betas;
            
            % set default parameters
            
            app.WavelengthnmEditField.Value = 13.5;
            app.NumericalApertureEditField.Value = .33;
            app.PhaseShiftEditField.Value = 3.14;
            app.PitchnmEditField.Value = 200;
            app.DosemJcm2EditField.Value = 200;
            app.ThresholddecimalEditField.Value = .5;
            app.NpixelsEditField.Value = 100;
            app.PeriodsEditField.Value = 5;
            app.FWHMnmEditField.Value = 50;
            
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % do nothing
        end

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
            % determine what delta corresponds to that material
            delta = app.deltas(strcmp(app.materials, app.MaterialsDropDown.Value));
            
            % determine real part of index of refraction
            n2 = 1 + delta;
            
            % 1) ask what the phase shift value is
            phaseShift = app.PhaseShiftEditField.Value;
            
            % 2) figure out what the correct unit is, and compute
            % phaseshift in correct unit
            unit = app.UnitsDropDown.Value;
            
            if strcmp(unit, 'radians') 
                phaseShift = app.PhaseShiftEditField.Value;
            elseif strcmp(unit, 'degrees')
                phaseShift = app.PhaseShiftEditField.Value*pi/180;
            elseif strcmp(unit, 'waves')
                phaseShift = app.PhaseShiftEditField.Value*2*pi;
            end
            
            % 3) compute thickness using correct phase shift unit          
            n1 = 1; % vacuum
            theta1 = 6*pi/180; % 6 degrees in radians
            theta2 = asin(n1/n2*sin(theta1));
            d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
            
            % if d is negative, then we know it's too small and must find
            % the next solution that is positive
            counter = 0;
            while (d<0)
                phaseShift = phaseShift-(2*pi);
                d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
                counter = counter+1;
                if (counter>50) % likely in an infinite loop
                    break
                end
            end
            
            % display optimal thickness
            app.ThicknessEditField.Value = d;
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %{ 
            conversion factor:
            1 pixel = lambda/(numOfPeriods*pitch)
            %}
            
            % parameters
            % material parameters
            delta = app.deltas(strcmp(app.materials, app.MaterialsDropDown.Value));
            beta = app.betas(strcmp(app.materials, app.MaterialsDropDown.Value));
            n2 = 1 + delta;
            
            % other parameters
            lambda = app.WavelengthnmEditField.Value;
            E0 = app.AmplitudenmEditField.Value;
            pitch = app.PitchnmEditField.Value;
            halfPitch = pitch/2;
            L = (2*app.ThicknessEditField.Value)/cosd(6);
            d = app.ThicknessEditField.Value;
            N = app.NpixelsEditField.Value;
            numOfPeriods = app.PeriodsEditField.Value;

            %{
            taking into account 3d mask effects (shadowing)
            - approximation that light ray path is a straight line
            %}

            %{
            mask
            %}
            
            % 1. defining the domain
            x = linspace(0, pitch*numOfPeriods, N);
            y = linspace(0, pitch*numOfPeriods, N); % for gaussian meshgrid

            % 2. defining region boundaries
            % initializing boundaries variable
            boundaries = [0];
            
            % defining boundary array (boundary array is in nm)
            for i = 1:numOfPeriods
                boundaries = [boundaries, ((2*i-1)*halfPitch)-(2*d*tand(6))];
                boundaries = [boundaries, (2*i-1)*halfPitch];
                boundaries = [boundaries, (i*pitch)-(2*d*tand(6))];
                boundaries = [boundaries, i*pitch];
            end
            
            % 3. defining each region
            % initializing each region
            region1 = false(1, N);
            region2 = false(1, N);
            region3 = false(1, N);
            region4 = false(1, N);
            
            % initializing grating variable
            grating = zeros(1, N);

            % defining each region using boundaries and setting them equal
            % to corresponding E field (note: shouldnt add logical arrays)
            for i = 1:(numOfPeriods*4)
                % region 1
                if mod(i, 4) == 1
                    region1 = (x >= boundaries(i) & x <= boundaries(i+1));
                    E1 = E0*(exp(1i*(2*pi*L)/lambda));
                    grating(region1) = E1;
                % region 2
                elseif mod(i, 4) == 2
                    region2 = (x >= boundaries(i) & x <= boundaries(i+1));
                    x2 = abs(x(region2)-((i/4)*pitch-(2*d*tand(6))));
                    path2a = abs((((L.*sind(6))-x2)./(sind(6)))); 
                    path2b = (L - path2a);
                    E2 = E0.*exp(1i.*(((2.*pi.*path2a)./lambda)+((2.*pi.*path2b.*n2)./lambda))).*exp(-((2.*pi.*beta).*path2b)./lambda);
                    grating(region2) = E2;
                % region 3
                elseif mod(i, 4) == 3
                    region3 = (x >= boundaries(i) & x <= boundaries(i+1));
                    E3 = E0*exp(1i*((2*pi*L*n2)/lambda))*exp(-(2*pi*L*beta)/lambda);
                    grating(region3) = E3;
                % region 4
                elseif mod(i, 4) == 0
                    region4 = (x >= boundaries(i) & x <= boundaries(i+1));
                    path4a = abs((x(region4)-((i/4)*pitch))./sind(6)); 
                    path4b = L - path4a;
                    E4 = E0.*exp(1i.*(((2.*pi.*path4a.*n2)./lambda)+((2.*pi.*path4b)./lambda))).*exp(-(2.*pi.*beta.*path4a)./lambda);
                    grating(region4) = E4;
                else 
                    disp('whoops something went wrong');
                end
            end

            % plot diffraction grating transmission function
            plot(app.MaskAxis, x, abs(grating))
            
            %{
            aerial image intensity
            %}
            
            % take FFT of grating
            abs(fftshift(fft(grating)));
            
             % convert to cutoff to pixel value
            cutoff = app.NumericalApertureEditField.Value/( ...
                app.WavelengthnmEditField.Value/app.PitchnmEditField.Value)*numOfPeriods;
            
            % define aperture function
            apertureFxn = zeros(1, app.NpixelsEditField.Value);
            apertureFxn(((app.NpixelsEditField.Value/2)+1)-cutoff:((app.NpixelsEditField.Value/2)+1)+cutoff) = 1;
            
            % multiply FFT of grating with aperture function
            image = fftshift(fft(grating)).*(apertureFxn);
            
            % take inverse FFT of image and plot
            plot(app.AerialImageAxes, x, abs((ifft(ifftshift((image))))).^2)
            aerialImage = abs((ifft(ifftshift((image))))).^2;
            
            %{
            wafer
            %}
            
            % convert from dosage to number of photons
            area = (pitch*(10^-9))*numOfPeriods*(E0*(10^-9))*(10^-14);
            energy = app.DosemJcm2EditField.Value*area; 
            numOfPhotons = energy*lambda*1000/(6.626*(10^-34)*3*(10^8));
            
            % n*n matrix of aerial image rows (to capture 2D properties)
            aerialImage = ones(size(aerialImage))'*aerialImage;
            
            % normalization
            aerialImage = aerialImage./max(aerialImage(:)).*numOfPhotons;
            
            % take into account shot noise
            aerialImage = aerialImage+sqrt(aerialImage).*randn(size(aerialImage));
            waferImage = aerialImage;
            
            % setting threshold
            waferImage(waferImage<app.DosemJcm2EditField.Value*app.ThresholddecimalEditField.Value) = 0;
            waferImage(waferImage>=app.DosemJcm2EditField.Value*app.ThresholddecimalEditField.Value) = 1;
                
            % take into account other stochastic effects
            % define parameters
            fwhm = app.FWHMnmEditField.Value*lambda/(numOfPeriods*pitch);
            sigma = fwhm/(2*sqrt(2*log(2)));
            mu = ((pitch*numOfPeriods)/2)+1;
            
            % create meshgrid for gaussian
            [X, Y] = meshgrid(x, y);
            
            % define 2d gaussian distribution
            gaussian = (1/sigma*sqrt(2*pi)).*exp(-(((X-mu).^2)/(2*(sigma^2)))-(((Y-mu).^2)/(2*(sigma^2))));
            
            % circularly convolve gaussian with wafer image
            waferImage = ifft2(fft2(waferImage).*fft2(gaussian));
            
            % plot new wafer image
            imagesc(app.WaferAxes, waferImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'radians', 'degrees', 'waves', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'radians';
            app.ThicknessButton = uibutton(app.UIFigure, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalThicknessLabel = uilabel(app.UIFigure);
            app.OptimalThicknessLabel.Text = 'Optimal Thickness';
            app.MaterialsDropDownLabel = uilabel(app.UIFigure);
            app.MaterialsDropDownLabel.Text = 'Materials';
            app.MaterialsDropDown = uidropdown(app.UIFigure);
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericalApertureEditFieldLabel = uilabel(app.UIFigure);
            app.NumericalApertureEditFieldLabel.Text = 'Numerical Aperture';
            app.NumericalApertureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchnmEditFieldLabel = uilabel(app.UIFigure);
            app.PitchnmEditFieldLabel.Text = 'Pitch (nm)';
            app.PitchnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaskAxis = uiaxes(app.UIFigure);
            title(app.MaskAxis, 'Mask Transmission Function')
            xlabel(app.MaskAxis, 'nm')
            ylabel(app.MaskAxis, '')
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.AerialImageAxes = uiaxes(app.UIFigure);
            title(app.AerialImageAxes, 'Aerial Image')
            xlabel(app.AerialImageAxes, 'nm')
            ylabel(app.AerialImageAxes, '')
            app.AerialImageLabel = uilabel(app.UIFigure);
            app.AerialImageLabel.Text = 'Predicted Image';
            app.WaferLabel = uilabel(app.UIFigure);
            app.WaferLabel.Text = '________________________________________________________________________________________________________';
            app.WaferAxes = uiaxes(app.UIFigure);
            title(app.WaferAxes, 'Wafer')
            xlabel(app.WaferAxes, 'nm')
            ylabel(app.WaferAxes, '')
            app.DosemJcm2EditFieldLabel = uilabel(app.UIFigure);
            app.DosemJcm2EditFieldLabel.Text = 'Dose (mJ/cm2)';
            app.DosemJcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholddecimalEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholddecimalEditFieldLabel.Text = 'Threshold (decimal)';
            app.ThresholddecimalEditField = uieditfield(app.UIFigure, 'numeric');
            app.NpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.NpixelsEditFieldLabel.Text = 'N (pixels)';
            app.NpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsEditFieldLabel.Text = 'Periods';
            app.PeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMnmEditFieldLabel = uilabel(app.UIFigure);
            app.FWHMnmEditFieldLabel.Text = 'FWHM (nm)';
            app.FWHMnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlursLabel = uilabel(app.UIFigure);
            app.BlursLabel.Text = 'Blurs';
            app.AmplitudenmEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudenmEditFieldLabel.Text = 'Amplitude (nm)';
            app.AmplitudenmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimal_thickness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end